classdef app_dialog_01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OKButton                       matlab.ui.control.Button
        PleasechooseexperimentaltestButtonGroup  matlab.ui.container.ButtonGroup
        ElectricthermicoventestButton  matlab.ui.control.RadioButton
        CSCtestButton                  matlab.ui.control.RadioButton
        PunkingtestButton              matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        selected_test = ''
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: 
        % PleasechooseexperimentaltestButtonGroup
        function PleasechooseexperimentaltestButtonGroupSelectionChanged(app, event)
            app.selected_test = app.PleasechooseexperimentaltestButtonGroup.SelectedObject;
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            disp('Pressed OK')
            disp(['Selected test: ', app.selected_test.Text])
            try
                app_main_punking
                app.app_dialog_01.Visible = 'off';
            catch
                disp('App main punking was not opened')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PleasechooseexperimentaltestButtonGroup = uibuttongroup(app.UIFigure);
            app.PleasechooseexperimentaltestButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PleasechooseexperimentaltestButtonGroupSelectionChanged, true);
            app.PleasechooseexperimentaltestButtonGroup.Title = 'Please choose experimental test';
            app.PunkingtestButton = uiradiobutton(app.PleasechooseexperimentaltestButtonGroup);
            app.PunkingtestButton.Text = 'Punking test';
            app.PunkingtestButton.Value = true;
            app.CSCtestButton = uiradiobutton(app.PleasechooseexperimentaltestButtonGroup);
            app.CSCtestButton.Text = 'CSC test';
            app.ElectricthermicoventestButton = uiradiobutton(app.PleasechooseexperimentaltestButtonGroup);
            app.ElectricthermicoventestButton.Text = 'Electric thermic oven test';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_dialog_01

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end