classdef app_main_punking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PunkingtestUIFigure          matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        ShowmodelresultButton        matlab.ui.control.Button
        CLabel_2                     matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        kgm3Label_2                  matlab.ui.control.Label
        FurnacetemperatureEditField  matlab.ui.control.EditField
        TemperatureEditFieldLabel    matlab.ui.control.Label
        LOIEditField                 matlab.ui.control.EditField
        LOILabel                     matlab.ui.control.Label
        DensityEditField             matlab.ui.control.EditField
        DensityEditFieldLabel        matlab.ui.control.Label
        ChosenvaluesLabel            matlab.ui.control.Label
        FurnacetemperatureKnob       matlab.ui.control.Knob
        CLabel                       matlab.ui.control.Label
        FurnacetemperatureLabel      matlab.ui.control.Label
        LOIKnob                      matlab.ui.control.Knob
        Label                        matlab.ui.control.Label
        LossonignitionLOILabel       matlab.ui.control.Label
        kgm3Label                    matlab.ui.control.Label
        DensityLabel                 matlab.ui.control.Label
        DensityKnob                  matlab.ui.control.Knob
        TabGroup                     matlab.ui.container.TabGroup
        Result1Tab                   matlab.ui.container.Tab
        Image                        matlab.ui.control.Image
        Result2Tab                   matlab.ui.container.Tab
    end

    
    properties (Access = private)
        density     = 30;
        LOI         = 1;
        furnace_temperature = 30;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DensityKnob
        function DensityKnobValueChanged(app, event)
            value = app.DensityKnob.Value;
            app.DensityKnob.Value=round(value/15) * 15;
            app.density = app.DensityKnob.Value;
            app.DensityEditField.Value = num2str(app.density);
        end

        % Value changed function: LOIKnob
        function LOIKnobValueChanged(app, event)
            value = app.LOIKnob.Value;
            app.LOIKnob.Value=round(value/0.5) * 0.5;
            app.LOI = app.LOIKnob.Value;
            app.LOIEditField.Value = num2str(app.LOI);
        end

        % Value changed function: FurnacetemperatureKnob
        function FurnacetemperatureKnobValueChanged(app, event)
            value = app.FurnacetemperatureKnob.Value;
            app.FurnacetemperatureKnob.Value=round(value/15) * 15;
            app.furnace_temperature = app.FurnacetemperatureKnob.Value;
            app.FurnacetemperatureEditField.Value = num2str(app.furnace_temperature);
        end

        % Button pushed function: ShowmodelresultButton
        function ShowmodelresultButtonPushed(app, event)
            app.Image.Visible = 'on';
        end

        % Value changed function: DensityEditField
        function DensityEditFieldValueChanged(app, event)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PunkingtestUIFigure = uifigure('Visible', 'off');
            app.PunkingtestUIFigure.Name = 'Punking test';
            app.TabGroup = uitabgroup(app.PunkingtestUIFigure);
            app.Result1Tab = uitab(app.TabGroup);
            app.Result1Tab.Title = 'Result 1';
            app.Image = uiimage(app.Result1Tab);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'example_result.JPG';
            app.Result2Tab = uitab(app.TabGroup);
            app.Result2Tab.Title = 'Result 2';
            app.Panel = uipanel(app.PunkingtestUIFigure);
            app.DensityKnob = uiknob(app.Panel, 'continuous');
            app.DensityKnob.Limits = [30 150];
            app.DensityKnob.MajorTicks = [30 45 60 75 90 105 120 135 150];
            app.DensityKnob.MajorTickLabels = {'30', '45', '60', '75', '90', '105', '120', '135', '150'};
            app.DensityKnob.ValueChangedFcn = createCallbackFcn(app, @DensityKnobValueChanged, true);
            app.DensityKnob.MinorTicks = [30 45 60 75 90 105 120 135 150];
            app.DensityKnob.Value = 30;
            app.DensityLabel = uilabel(app.Panel);
            app.DensityLabel.Text = 'Density';
            app.kgm3Label = uilabel(app.Panel);
            app.kgm3Label.Interpreter = 'latex';
            app.kgm3Label.Text = {'$kg/m^3$'; ''};
            app.LossonignitionLOILabel = uilabel(app.Panel);
            app.LossonignitionLOILabel.Text = 'Loss on ignition (LOI)';
            app.Label = uilabel(app.Panel);
            app.Label.Interpreter = 'latex';
            app.Label.Text = '$\%$';
            app.LOIKnob = uiknob(app.Panel, 'continuous');
            app.LOIKnob.Limits = [1 7];
            app.LOIKnob.ValueChangedFcn = createCallbackFcn(app, @LOIKnobValueChanged, true);
            app.LOIKnob.MinorTicks = [1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7];
            app.LOIKnob.Value = 1;
            app.FurnacetemperatureLabel = uilabel(app.Panel);
            app.FurnacetemperatureLabel.Text = 'Furnace temperature';
            app.CLabel = uilabel(app.Panel);
            app.CLabel.Interpreter = 'latex';
            app.CLabel.Text = '$°C$';
            app.FurnacetemperatureKnob = uiknob(app.Panel, 'continuous');
            app.FurnacetemperatureKnob.Limits = [30 150];
            app.FurnacetemperatureKnob.ValueChangedFcn = createCallbackFcn(app, @FurnacetemperatureKnobValueChanged, true);
            app.FurnacetemperatureKnob.MinorTicks = [30 45 60 75 90 105 120 135 150];
            app.FurnacetemperatureKnob.Value = 30;
            app.ChosenvaluesLabel = uilabel(app.Panel);
            app.ChosenvaluesLabel.Text = 'Chosen values';
            app.DensityEditFieldLabel = uilabel(app.Panel);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityEditField = uieditfield(app.Panel, 'text');
            app.DensityEditField.ValueChangedFcn = createCallbackFcn(app, @DensityEditFieldValueChanged, true);
            app.DensityEditField.Editable = 'off';
            app.LOILabel = uilabel(app.Panel);
            app.LOILabel.Text = 'LOI';
            app.LOIEditField = uieditfield(app.Panel, 'text');
            app.LOIEditField.Editable = 'off';
            app.TemperatureEditFieldLabel = uilabel(app.Panel);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.FurnacetemperatureEditField = uieditfield(app.Panel, 'text');
            app.FurnacetemperatureEditField.Editable = 'off';
            app.kgm3Label_2 = uilabel(app.Panel);
            app.kgm3Label_2.Interpreter = 'latex';
            app.kgm3Label_2.Text = {'$kg/m^3$'; ''};
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Interpreter = 'latex';
            app.Label_2.Text = '$\%$';
            app.CLabel_2 = uilabel(app.Panel);
            app.CLabel_2.Interpreter = 'latex';
            app.CLabel_2.Text = '$°C$';
            app.ShowmodelresultButton = uibutton(app.Panel, 'push');
            app.ShowmodelresultButton.ButtonPushedFcn = createCallbackFcn(app, @ShowmodelresultButtonPushed, true);
            app.ShowmodelresultButton.Text = 'Show model result';
            % Show the figure after all components are created
            app.PunkingtestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_main_punking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PunkingtestUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PunkingtestUIFigure)
        end
    end
end